classdef REACHING_STAR_DEMO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Hyperlink2                      matlab.ui.control.Hyperlink
        MarsdemoButton                  matlab.ui.control.Button
        LunardemoButton                 matlab.ui.control.Button
        ReachingSTARLabel               matlab.ui.control.Label
        Hyperlink_3                     matlab.ui.control.Hyperlink
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        CurrentcelestialbodyLabel       matlab.ui.control.Label
        DetecttheMooooveLabel           matlab.ui.control.Label
        ElapsedLabel                    matlab.ui.control.Label
        DifferentstagesButtonGroup      matlab.ui.container.ButtonGroup
        Step4DetectionresultButton      matlab.ui.control.ToggleButton
        Step3DetectpeaksButton          matlab.ui.control.ToggleButton
        Step2SmoothiingButton           matlab.ui.control.ToggleButton
        Step1FilteringButton            matlab.ui.control.ToggleButton
        Step0OriginalDataButton         matlab.ui.control.ToggleButton
        Celestialbodyselect             matlab.ui.control.Switch
        Message                         matlab.ui.control.Label
        EnterButton                     matlab.ui.control.Button
        DataPathonlycsvallowedTextArea  matlab.ui.control.TextArea
        DataPathonlycsvallowedTextAreaLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        smoothing_std = 6e2;            % std of the Gaussian smoothing
        peak_std_num = 2;               % number of std to be recognized as a peak
        slope_ratio_threshold = 1.5;    %
        detect_level = 0.3;
        bp_filter_coef = [0.004754038,0.003773327,0.039122646,0.041241026,-0.001421596,...
                          -0.005834329,0.031413306,0.00830756,-0.06737037,-0.064169451,...
                          -0.001848996,-0.058183875,-0.196796055,-0.124611171,0.206388764,...
                          0.406987632,0.206388764,-0.124611171,-0.196796055,-0.058183875,...
                          -0.001848996,-0.064169451,-0.06737037,0.00830756,0.031413306,...
                          -0.005834329,-0.001421596,0.041241026,0.039122646,0.003773327,0.004754038];
        ts = 0;
        state = 0;
        time = 0;
        velocity = 0;
        velocity_original = 0;
        velocity_bandpass = 0;
        velocity_envelope_generation = 0;

        peak_indices = 0;
        slope_ratios = 0;
        peaks = 0;
        peaks_founded = 0;
        level = 0;
        left_slopes = 0;
        right_slopes = 0;
        lower_slopes = 0;
        upper_slopes = 0;

        line_width = 1.2;
    end
    
    methods (Access = private)
        function reset(app)
            cla(app.UIAxes);
            app.Message.Text = sprintf("Paste the filepath to Data Path block, then press the button ""Enter"" to load data.\n" + ...
                                       "(Simpling press enter on keyboard would not work)");
            app.DifferentstagesButtonGroup.SelectedObject = app.Step0OriginalDataButton;
            app.UIAxes.XLabel.String = 'X';
            app.UIAxes.YLabel.String = 'Y';
            app.UIAxes.Title.String = "Title";

            app.ts = 0;
            app.state = 0;
            app.time = 0;
            app.velocity = 0;
            app.velocity_original = 0;
            app.velocity_bandpass = 0;
            app.velocity_envelope_generation = 0;

            app.peak_indices = 0;
            app.slope_ratios = 0;
            app.peaks = 0;
            app.peaks_founded = 0;
            app.level = 0;
            app.left_slopes = 0;
            app.right_slopes = 0;
            app.lower_slopes = 0;
            app.upper_slopes = 0;
    
            app.line_width = 1.2;

            app.DifferentstagesButtonGroup.SelectedObject = app.Step0OriginalDataButton;
        end

        function [values, locations, level] = peaksfinder(~, y, std_num, width_factor)
            %% Prepare
            % Calculate Average and STD
            mu = mean(y);
            sigma = std(y);
            % Calculate STD Levels
            level = mu + sigma .* std_num;
        
            %% Main Calculation
            locations = cell(1, 1);
            values = cell(1, 1);
        
            % Calculate crossing points
            crossing_indices = [find((y(1:end - 1) < level & y(2:end) > level) | (y(1:end - 1) > level & y(2:end) < level))];
            right_most_index = 1;
            for i = 1:length(crossing_indices) - 1
                % Peak merging
                if (crossing_indices(i) <= right_most_index)
                    continue;
                end
                guess_max_value = [];
                guess_max_index = [];
                % Try to find all peaks
        
                % Find peaks
                segment_indices = crossing_indices(i):crossing_indices(i + 1);
                segment = y(segment_indices);
                [max_value, max_index] = max(segment);
                max_index = max_index + crossing_indices(i) - 1; % local to global index
                if max_value <= level
                    continue;
                end
        
                for ii = crossing_indices(i) + 1 : crossing_indices(i+1) - 1
                    if y(ii) >= y(ii-1) && y(ii) >= y(ii+1) && y(ii) >= y(max_index) * 0.5 + level*0.5
                        max_value = y(ii);
                        max_index = ii;
                        break;
                    end
                end
        
                % Calculate peak size
                left_index = find(y(1:max_index) <= max_value * width_factor, 1, "last");
                right_index = find(y(max_index:end) <= max_value * width_factor, 1, "first");
                if isempty(left_index)
                    left_index = 1;
                end
                if isempty(right_index)
                    right_index = length(y);
                end
                right_index = right_index + max_index - 1; % local to global index
                right_most_index = max_index + right_index - left_index; % for peak merging
        
                % Store Results
                locations{1} = [locations{1}; [left_index, max_index, right_index]];
                values{1} = [values{1}; max_value];
            end
            locations = locations{1};
            values = values{1};
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DataPathonlycsvallowedTextArea
        function DataPathonlycsvallowedTextAreaValueChanged(app, event)

        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.Message.Text = sprintf("Loading...");
            drawnow;
            cla(app.UIAxes);
            filepath = string(app.DataPathonlycsvallowedTextArea.Value);
            try
                app.UIAxes.XLabel.String = 'Time[s]';
                app.UIAxes.YLabel.String = 'Vel[m/s]';
                app.UIAxes.Title.String = "Original Data";
                app.DifferentstagesButtonGroup.SelectedObject = app.Step0OriginalDataButton;

                data_table = readtable(filepath, 'VariableNamingRule', 'preserve');
                app.time = data_table{:, 2};
                app.velocity = data_table{:, 3};
                app.velocity_original = app.velocity;
                app.ts = app.time(2) - app.time(1);
                app.UIAxes.XLim = ([app.time(1), app.time(end)]);
                app.UIAxes.YLim = ([min(app.velocity_original), max(app.velocity_original)]);

                if app.ts == 0.05
                    app.Celestialbodyselect.Value = 'Mars';
                else
                    app.Celestialbodyselect.Value = "Lunar";
                end

                app.Message.Text = sprintf("Now showing: Original data");                
                plot(app.UIAxes,app.time,app.velocity_original);

                app.Message.Text = sprintf("Computing...");
                drawnow;
                tic;

                %% Step 1: Original Data Through Bandpass Filter
                app.velocity = conv(app.velocity, app.bp_filter_coef, 'same');
                app.velocity_bandpass = app.velocity;
                app.state = 1;

                %% Step 2. Profile Extraction and Smoothing of Data

                % Absolute value
                app.velocity = abs(app.velocity);
                
                % Gaussian smoothing
                window_size = 6 * app.smoothing_std;
                x = -floor(window_size / 2):1:floor(window_size / 2);
                gaussian_kernel = exp(-x .^ 2 / (2 * app.smoothing_std ^ 2));
                gaussian_kernel = gaussian_kernel / sum(gaussian_kernel);
                app.velocity = conv(app.velocity, gaussian_kernel, 'same');
                
                % Normalization
                average = mean(app.velocity);
                app.velocity = app.velocity - average;
                app.velocity = app.velocity .* (app.velocity >= 0);

                app.velocity_envelope_generation = app.velocity;

                %% Step 3. Peaks from Profile Selected, with Slopes Calculated for Thresholding
                % Find peak
                [app.peaks_founded, app.peak_indices, app.level] = app.peaksfinder(app.velocity, app.peak_std_num, app.detect_level);
                                
                % Slopes
                app.left_slopes = zeros(length(app.peak_indices), 1);
                app.right_slopes = zeros(length(app.peak_indices), 1);
                app.slope_ratios = zeros(length(app.peak_indices), 1);
                for i = 1:size(app.peak_indices, 1)
                    left = app.peak_indices(i, 1);
                    mid = app.peak_indices(i, 2);
                    right = app.peak_indices(i, 3);
                    left_slope = (app.velocity(mid) - app.velocity(left)) / (mid - left);
                    right_slope = (app.velocity(right) - app.velocity(mid)) / (right - mid);
                    ratio = abs(left_slope / right_slope);
                    % Store results
                    app.left_slopes(i) = left_slope;
                    app.right_slopes(i) = right_slope;
                    app.slope_ratios(i) = ratio;
                end
                
                %% Step 4. Peaks Selected as Seismic Event and Onset Time Estimated
                app.peaks = [];
                app.lower_slopes = [];
                app.upper_slopes = [];
                for i = 1:size(app.peak_indices, 1)
                    % if (abs(right_slopes(i)) < slope_threshold) && (slope_ratios(i) > slope_ratio_threshold)
                    if (app.slope_ratios(i) > app.slope_ratio_threshold)
                        app.peaks = [app.peaks app.peak_indices(i, 2)];
                        app.lower_slopes = [app.lower_slopes app.left_slopes(i)];
                        app.upper_slopes = [app.upper_slopes app.right_slopes(i)];

                    end
                end
                tEnd = toc;
                app.DifferentstagesButtonGroup.SelectedObject = app.Step0OriginalDataButton;
                app.Message.Text = sprintf("Computation complete. You're now viewing original data.\n" + ...
                                           "Press any other stages to learn about them.");
                app.ElapsedLabel.Text = sprintf("After data loaded, last computation took %f miliseconds to compute final result.", tEnd*1000);
            catch
                app.reset();
                app.Message.Text = sprintf("Wrong format or unexpected error! Please retry......\n\n" + ...
                                           "Paste the filepath to Data Path block, then press the button ""Enter"" to load data.\n" + ...
                                           "(Simpling press enter on keyboard would not work)" + ...
                                           "Note: Remember to remove "" before and after the file name if you use ""Copy as Path""");
                app.DataPathonlycsvallowedTextArea.Value = '';
            end
        end

        % Value changed function: Celestialbodyselect
        function CelestialbodyselectValueChanged(app, event)
            value = app.Celestialbodyselect.Value;
            app.reset();
            app.Celestialbodyselect.Value = value;

            if value == "Lunar"
                app.smoothing_std = 6e2;            % std of the Gaussian smoothing
                app.peak_std_num = 2;               % number of std to be recognized as a peak
                app.slope_ratio_threshold = 1.5;    %
                app.detect_level = 0.3;
                app.bp_filter_coef = [0.004754038,0.003773327,0.039122646,0.041241026,-0.001421596,...
                                  -0.005834329,0.031413306,0.00830756,-0.06737037,-0.064169451,...
                                  -0.001848996,-0.058183875,-0.196796055,-0.124611171,0.206388764,...
                                  0.406987632,0.206388764,-0.124611171,-0.196796055,-0.058183875,...
                                  -0.001848996,-0.064169451,-0.06737037,0.00830756,0.031413306,...
                                  -0.005834329,-0.001421596,0.041241026,0.039122646,0.003773327,0.004754038];
            else
                app.smoothing_std = 3e2; % std of the Gaussian smoothing
                app.peak_std_num = 1.3; % number of std to be recognized as a peak (recommend 2~3)
                app.slope_ratio_threshold = 1.5; %
                app.detect_level = 0.3;
                app.bp_filter_coef = [0.015753723,-0.039009518,-0.032765272,-0.006810152,-0.001507097,...
                                  -0.034209679,-0.069394178,-0.059643647,-0.012730875,0.005371116,...
                                  -0.049134331,-0.124987344,-0.110448191,0.04424677,0.249863191,...
                                  0.345144188,0.249863191,0.04424677,-0.110448191,-0.124987344,...
                                  -0.049134331,0.005371116,-0.012730875,-0.059643647,-0.069394178,...
                                  -0.034209679,-0.001507097,-0.006810152,-0.032765272,-0.039009518,-0.015753723];
            end
        end

        % Selection changed function: DifferentstagesButtonGroup
        function DifferentstagesButtonGroupSelectionChanged(app, event)
            selectedButton = string(app.DifferentstagesButtonGroup.SelectedObject.Text(1));
            cla(app.UIAxes);
            if app.state == 0
                return
            end

            switch (selectedButton)
                case "Step 0."
                    app.UIAxes.XLabel.String = 'Time[s]';
                    app.UIAxes.YLabel.String = 'Vel[m/s]';
                    app.UIAxes.Title.String = "Original Data";
                    
                    app.UIAxes.XLim = ([app.time(1), app.time(end)]);
                    app.UIAxes.YLim = ([min(app.velocity_original), max(app.velocity_original)]);

                    app.Message.Text = sprintf("You're now viewing the Original data.\n" + ...
                                               "Press any other stages to learn about them.");                
                    plot(app.UIAxes,app.time,app.velocity_original);
                case "Step 1."                    
                    app.UIAxes.XLabel.String = 'Time[s]';
                    app.UIAxes.YLabel.String = 'Vel[m/s]';
                    app.UIAxes.Title.String = "Step1: Bandpass Filter";

                    app.UIAxes.XLim = ([app.time(1), app.time(end)]);
                    app.UIAxes.YLim = ([min(app.velocity_original), max(app.velocity_original)]);

                    app.Message.Text = sprintf("Step 1. Original Data Through Bandpass Filter\n\n" + ...
                                               "The blue one is the original data, while the orange one is the filtered data.\n\n" + ...
                                               "It is clearly shown that several unwanted peaks are filtered.\n" + ...
                                               "This helps us preliminarily exclude some noises that we are not interested in, leaving a cleaner profile.");                
                    plot(app.UIAxes,app.time,app.velocity_original, 'color', '#0072BD');
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.time,app.velocity_bandpass, 'color', '#EDB120');
                
                case "Step 2."                    
                    app.UIAxes.XLabel.String = 'Time[s]';
                    app.UIAxes.YLabel.String = 'Amplitude';
                    app.UIAxes.Title.String = "Step2: Absolute Value + Smoothing + Normalization";

                    app.UIAxes.XLim = ([app.time(1), app.time(end)]);
    
                    app.Message.Text = sprintf("Step 2. Profile Extraction and Smoothing of Data\n\n" + ...
                                               "In this step, we applied Gaussian smoothing and normalization.\n" + ...
                                               "These filters help us further reduce unwanted peaks.\n" + ...
                                               "The cleaner profile also allows simpler future analysis.");                
                    plot(app.UIAxes,app.time,app.velocity_envelope_generation);   

                case "Step 3."
                    app.UIAxes.XLabel.String = 'Time[s]';
                    app.UIAxes.YLabel.String = 'Amplitude';
                    app.UIAxes.Title.String = "Step3. Find Peaks & Slopes";

                    app.UIAxes.XLim = ([app.time(1), app.time(end)]);
                    % app.UIAxes.YLim = ([min(app.velocity), max(app.velocity)]);
    
                    app.Message.Text = sprintf("Step 3. Peaks from Profile Selected, with Slopes Calculated for Thresholding\n\n" + ...
                                               "The light blue line represents the detection threshold for intensity, " + ...
                                               "the red vertical lines represent discovered peak positions, \n" + ...
                                               "the green dotted lines represent the slopes to the left and right of the peaks,\n" + ...
                                               "and the pink lines represent the magnitude of the slope ratio (left divided by right).\n" + ...
                                               "These peaks are candidates to seismic events. We'll need to further check their slope in the next step.");
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes,app.time, app.velocity_envelope_generation, "LineWidth", app.line_width);
                    xline(app.UIAxes,app.peak_indices(:, 2) * app.ts, "LineWidth", app.line_width, "Color", "red");
                    yline(app.UIAxes,app.level, "LineWidth", app.line_width, "Color", "cyan");
                    for i = 1:size(app.peak_indices, 1)
                        % slope
                        plot(app.UIAxes, app.ts * app.peak_indices(i, :), app.velocity_envelope_generation(app.peak_indices(i, :)), "LineWidth", app.line_width, "Color", "green" , "LineStyle", "--");
                        % ratio
                        x0 = app.peak_indices(i, 2);
                        x1 = x0 + app.slope_ratios(i) * 5e3;
                        y0 = app.peaks_founded(i);
                        plot(app.UIAxes,app.ts * [x0, x1], [y0, y0], "LineWidth", app.line_width, "Color", "magenta")
                    end
                    hold(app.UIAxes, 'off');

                case "Step 4."
                    app.UIAxes.XLabel.String = 'Time[s]';
                    app.UIAxes.YLabel.String = 'Vel[m/s]';
                    app.UIAxes.Title.String = "Step4. Pick Peaks";

                    app.UIAxes.XLim = ([app.time(1), app.time(end)]);
                    app.UIAxes.YLim = ([min(app.velocity_original), max(app.velocity_original)]);

                    app.Message.Text = sprintf("Step 4. Peaks Selected as Seismic Event and Onset Time Estimated\n\n" + ...
                                               "The red lines represent our detected start time based on the slope ratio, \n" + ...
                                               "and the green boxes mark the duration of each seismic event.\n\n" + ...
                                               "The left and right slopes are further extended to intersect the horizontal axis, \n" + ...
                                               "marking the onset and the end to the seismic event.");
                    hold(app.UIAxes, 'on');
                    if ~isempty(app.peaks)
                        for i = 1:length(app.peaks)
                            x1 = app.peaks(i);
                            y1 = app.velocity(x1);
                            x0 = x1 - y1 / app.lower_slopes(i);
                            x2 = x1 - y1 / app.upper_slopes(i);
                            xline(app.UIAxes, x0 * app.ts, "LineWidth", app.line_width, "Color", "red");
                            p1 = patch(app.UIAxes, [x0, x2, x2, x0] * app.ts, [min(app.velocity_original), min(app.velocity_original), max(app.velocity_original), max(app.velocity_original)], 'green');
                            p1.EdgeColor = 'none';
                            p1.FaceVertexAlphaData = 0.2;
                            p1.FaceAlpha = 'flat';
                        end
                    end
                    plot(app.UIAxes, app.time, app.velocity_original, "LineWidth", app.line_width);
            end

        end

        % Button pushed function: LunardemoButton
        function LunardemoButtonPushed(app, event)
            app.DataPathonlycsvallowedTextArea.Value = "demo_lunar.csv";
            app.Message.Text = sprintf("Press the button ""Enter"" to continue\n");
        end

        % Button pushed function: MarsdemoButton
        function MarsdemoButtonPushed(app, event)
            app.DataPathonlycsvallowedTextArea.Value = "demo_mars.csv";
            app.Message.Text = sprintf("Press the button ""Enter"" to continue\n");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataPathonlycsvallowedTextAreaLabel = uilabel(app.UIFigure);
            app.DataPathonlycsvallowedTextAreaLabel.Text = {'Data Path'; '(only .csv allowed)'};
            app.DataPathonlycsvallowedTextArea = uitextarea(app.UIFigure);
            app.DataPathonlycsvallowedTextArea.ValueChangedFcn = createCallbackFcn(app, @DataPathonlycsvallowedTextAreaValueChanged, true);
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.Message = uilabel(app.UIFigure);
            app.Message.Text = {'Paste the filepath to Data Path block, then press the button "Enter" to load data.'; '(Simpling press enter on keyboard would not work)'};
            app.Celestialbodyselect = uiswitch(app.UIFigure, 'slider');
            app.Celestialbodyselect.Items = {'Lunar', 'Mars'};
            app.Celestialbodyselect.ValueChangedFcn = createCallbackFcn(app, @CelestialbodyselectValueChanged, true);
            app.Celestialbodyselect.Value = 'Lunar';
            app.DifferentstagesButtonGroup = uibuttongroup(app.UIFigure);
            app.DifferentstagesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DifferentstagesButtonGroupSelectionChanged, true);
            app.DifferentstagesButtonGroup.Title = 'Different stages';
            app.Step0OriginalDataButton = uitogglebutton(app.DifferentstagesButtonGroup);
            app.Step0OriginalDataButton.Text = {'Step 0.'; 'Original Data'};
            app.Step0OriginalDataButton.Value = true;
            app.Step1FilteringButton = uitogglebutton(app.DifferentstagesButtonGroup);
            app.Step1FilteringButton.Text = {'Step 1.'; 'Filtering'};
            app.Step2SmoothiingButton = uitogglebutton(app.DifferentstagesButtonGroup);
            app.Step2SmoothiingButton.Text = {'Step 2.'; 'Smoothiing'};
            app.Step3DetectpeaksButton = uitogglebutton(app.DifferentstagesButtonGroup);
            app.Step3DetectpeaksButton.Text = {'Step 3.'; 'Detect peaks'};
            app.Step4DetectionresultButton = uitogglebutton(app.DifferentstagesButtonGroup);
            app.Step4DetectionresultButton.Text = {'Step 4.'; 'Detection result'};
            app.ElapsedLabel = uilabel(app.UIFigure);
            app.ElapsedLabel.Text = 'No previous attempt.';
            app.DetecttheMooooveLabel = uilabel(app.UIFigure);
            app.DetecttheMooooveLabel.Text = 'Detect the Moooove';
            app.CurrentcelestialbodyLabel = uilabel(app.UIFigure);
            app.CurrentcelestialbodyLabel.Text = {'Current celestial body'; '(Auto detect)'};
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://www.spaceappschallenge.org/nasa-space-apps-2024/find-a-team/reaching-star/?tab=project';
            app.Hyperlink.Text = 'Our NASA Space App Challenge Website';
            app.Hyperlink_2 = uihyperlink(app.UIFigure);
            app.Hyperlink_2.URL = 'https://rakechen-0307.github.io/detect-the-moooove';
            app.Hyperlink_2.Text = 'Online Demo';
            app.Hyperlink_3 = uihyperlink(app.UIFigure);
            app.Hyperlink_3.URL = 'https://github.com/rakechen-0307/NASA-App-Challenge';
            app.Hyperlink_3.Text = 'Source Code';
            app.ReachingSTARLabel = uilabel(app.UIFigure);
            app.ReachingSTARLabel.Text = 'Reaching STAR';
            app.LunardemoButton = uibutton(app.UIFigure, 'push');
            app.LunardemoButton.ButtonPushedFcn = createCallbackFcn(app, @LunardemoButtonPushed, true);
            app.LunardemoButton.Text = 'Lunar demo';
            app.MarsdemoButton = uibutton(app.UIFigure, 'push');
            app.MarsdemoButton.ButtonPushedFcn = createCallbackFcn(app, @MarsdemoButtonPushed, true);
            app.MarsdemoButton.Text = 'Mars demo';
            app.Hyperlink2 = uihyperlink(app.UIFigure);
            app.Hyperlink2.URL = 'https://wufs.wustl.edu/SpaceApps/data/space_apps_2024_seismic_detection.zip';
            app.Hyperlink2.Text = {'NASA Space Apps Data Packet'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = REACHING_STAR_DEMO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end